classdef PlataformaExperimentacion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PlataformaExperimentacinUIFigure  matlab.ui.Figure
        psi                          matlab.ui.control.Label
        Label_9                      matlab.ui.control.Label
        theta                        matlab.ui.control.Label
        Label_8                      matlab.ui.control.Label
        phi                          matlab.ui.control.Label
        Label_7                      matlab.ui.control.Label
        Z                            matlab.ui.control.Label
        ZLabel                       matlab.ui.control.Label
        Y                            matlab.ui.control.Label
        YLabel                       matlab.ui.control.Label
        X                            matlab.ui.control.Label
        XLabel                       matlab.ui.control.Label
        PosicindelefectorfinalLabel  matlab.ui.control.Label
        CinemticadirectaLabel        matlab.ui.control.Label
        EditField_3                  matlab.ui.control.NumericEditField
        EditField_2                  matlab.ui.control.NumericEditField
        EditField                    matlab.ui.control.NumericEditField
        q3Slider                     matlab.ui.control.Slider
        q3SliderLabel                matlab.ui.control.Label
        q2Slider                     matlab.ui.control.Slider
        q2SliderLabel                matlab.ui.control.Label
        q1Slider                     matlab.ui.control.Slider
        q1SliderLabel                matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Robot;
    end
    
    methods (Access = public)
        function draw(app)
            xlim([-700 700]);
            ylim([-700 700]);
            zlim([-20  900]);

            tht1=app.q1Slider.Value;
            tht2=app.q2Slider.Value;
            tht3=app.q3Slider.Value;
            
            assignin('base','tht1',tht1);
            assignin('base','tht2',tht2);
            assignin('base','tht3',tht3);
            
            set_param(gcs,'SimulationCommand','start');

            q=[tht1,tht2,tht3];
            app.UIAxes.Visible='on';
            app.Robot.plot(q);
            PMTH=app.Robot.fkine(q);
            X=[transl(PMTH);flip(tr2rpy(PMTH,'zyx')')];
            app.X.Text =string(X(1));
            app.Y.Text=string(X(2));
            app.Z.Text=string(X(3));
            app.phi.Text=string(X(4));
            app.theta.Text=string(X(5));
            app.psi.Text=string(X(6));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
load_system("EnsambleInterfaz.slx");
find_system();
L1=109; L2= 89.5; L3=144.575; L4=159.5;

ws= [-700 700 -700 700 -20 900];
plot_options = {'noshadow','nobase','noname','workspace',ws,'scale',.5,'view',[42 35], 'tilesize',2,  'ortho', 'lightpos',[2 2 10] };
L(1) = Link('revolute','alpha',    0,'a',  0,'d', L1,'offset',    0,'qlim',[0 2*pi],'modified');
L(2) = Link('revolute','alpha',-pi/2,'a',  0,'d', L2,'offset',    0,'qlim',[0 2*pi],'modified');
L(3) = Link('revolute','alpha',    0,'a',-L3,'d',  0,'offset',    0,'qlim',[0 2*pi],'modified');

app.Robot = SerialLink(L,'name','Robot1','plotopt',plot_options);

app.Robot.tool = [  0   0  -1 -L4  ;
                -1   0   0   0  ;
                 0   1   0   0  ;
                 0   0   0   1  ];
app.Robot.base=[0 -1  0   0;
                0  0 -1   0;
                1  0  0 420;
                0  0  0   1];

app.PlataformaExperimentacinUIFigure.HandleVisibility = 'callback';
        end

        % Value changed function: q1Slider
        function q1SliderValueChanged(app, event)
            value = app.q1Slider.Value;
            app.EditField.Value=value;
            draw(app);
        end

        % Value changed function: q2Slider
        function q2SliderValueChanged(app, event)
            value = app.q2Slider.Value;
             app.EditField_2.Value=value;
             draw(app);
        end

        % Value changed function: q3Slider
        function q3SliderValueChanged(app, event)
            value = app.q3Slider.Value;
             app.EditField_3.Value=value;
             draw(app);
        end

        % Callback function
        function J4SliderValueChanged(app, event)
            value = app.J4Slider.Value;
             app.EditField_4.Value=value;
             draw(app);
        end

        % Callback function
        function J5SliderValueChanged(app, event)
            value = app.J5Slider.Value;
             app.EditField_5.Value=value;
             draw(app);
        end

        % Callback function
        function J6SliderValueChanged(app, event)
            value = app.J6Slider.Value;
             app.EditField_6.Value=value;
             draw(app);
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
             app.q1Slider.Value=value;
             draw(app);
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
              app.q2Slider.Value=value;
              draw(app);
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;
              app.q3Slider.Value=value;
              draw(app);
        end

        % Callback function
        function EditField_4ValueChanged(app, event)
            value = app.EditField_4.Value;
              app.J4Slider.Value=value;
              draw(app);
        end

        % Callback function
        function EditField_5ValueChanged(app, event)
            value = app.EditField_5.Value;
              app.J5Slider.Value=value;
              draw(app);
        end

        % Callback function
        function EditField_6ValueChanged(app, event)
            value = app.EditField_6.Value;
              app.J6Slider.Value=value;
              draw(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PlataformaExperimentacinUIFigure = uifigure('Visible', 'off');
            app.PlataformaExperimentacinUIFigure.Name = 'Plataforma Experimentación';
            app.PlataformaExperimentacinUIFigure.Icon = 'icon.png';
            app.UIAxes = uiaxes(app.PlataformaExperimentacinUIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.q1SliderLabel = uilabel(app.PlataformaExperimentacinUIFigure);
            app.q1SliderLabel.Text = 'q1';
            app.q1Slider = uislider(app.PlataformaExperimentacinUIFigure);
            app.q1Slider.Limits = [-0.785398163397448 0.785398163397448];
            app.q1Slider.ValueChangedFcn = createCallbackFcn(app, @q1SliderValueChanged, true);
            app.q2SliderLabel = uilabel(app.PlataformaExperimentacinUIFigure);
            app.q2SliderLabel.Text = 'q2';
            app.q2Slider = uislider(app.PlataformaExperimentacinUIFigure);
            app.q2Slider.Limits = [-0.785398163397448 3.14159265358979];
            app.q2Slider.ValueChangedFcn = createCallbackFcn(app, @q2SliderValueChanged, true);
            app.q3SliderLabel = uilabel(app.PlataformaExperimentacinUIFigure);
            app.q3SliderLabel.Text = 'q3';
            app.q3Slider = uislider(app.PlataformaExperimentacinUIFigure);
            app.q3Slider.Limits = [-1.57910741847106 1.57910741847106];
            app.q3Slider.ValueChangedFcn = createCallbackFcn(app, @q3SliderValueChanged, true);
            app.EditField = uieditfield(app.PlataformaExperimentacinUIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField_2 = uieditfield(app.PlataformaExperimentacinUIFigure, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_3 = uieditfield(app.PlataformaExperimentacinUIFigure, 'numeric');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.CinemticadirectaLabel = uilabel(app.PlataformaExperimentacinUIFigure);
            app.CinemticadirectaLabel.Text = 'Cinemática directa';
            app.PosicindelefectorfinalLabel = uilabel(app.PlataformaExperimentacinUIFigure);
            app.PosicindelefectorfinalLabel.Text = 'Posición del efector final';
            app.XLabel = uilabel(app.PlataformaExperimentacinUIFigure);
            app.XLabel.Text = 'X:';
            app.X = uilabel(app.PlataformaExperimentacinUIFigure);
            app.X.Text = '';
            app.YLabel = uilabel(app.PlataformaExperimentacinUIFigure);
            app.YLabel.Text = 'Y:';
            app.Y = uilabel(app.PlataformaExperimentacinUIFigure);
            app.Y.Text = '';
            app.ZLabel = uilabel(app.PlataformaExperimentacinUIFigure);
            app.ZLabel.Text = 'Z:';
            app.Z = uilabel(app.PlataformaExperimentacinUIFigure);
            app.Z.Text = '';
            app.Label_7 = uilabel(app.PlataformaExperimentacinUIFigure);
            app.Label_7.Text = 'φ:';
            app.phi = uilabel(app.PlataformaExperimentacinUIFigure);
            app.phi.Text = '';
            app.Label_8 = uilabel(app.PlataformaExperimentacinUIFigure);
            app.Label_8.Text = 'θ:';
            app.theta = uilabel(app.PlataformaExperimentacinUIFigure);
            app.theta.Text = '';
            app.Label_9 = uilabel(app.PlataformaExperimentacinUIFigure);
            app.Label_9.Text = 'ψ:';
            app.psi = uilabel(app.PlataformaExperimentacinUIFigure);
            app.psi.Text = '';
            % Show the figure after all components are created
            app.PlataformaExperimentacinUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlataformaExperimentacion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PlataformaExperimentacinUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PlataformaExperimentacinUIFigure)
        end
    end
end